classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        FirstFilterDropDownLabel    matlab.ui.control.Label
        FirstFilterDropDown         matlab.ui.control.DropDown
        SecondFilterDropDownLabel   matlab.ui.control.Label
        SecondFilterDropDown        matlab.ui.control.DropDown
        uploadButton                matlab.ui.control.StateButton
        FilteredimagesListBoxLabel  matlab.ui.control.Label
        FilteredimagesListBox       matlab.ui.control.ListBox
        SimilarityThresholdEditField_3Label  matlab.ui.control.Label
        SimilarityThreshold2        matlab.ui.control.NumericEditField
        goButton                    matlab.ui.control.Button
        SimilarityThresholdEditField_4Label  matlab.ui.control.Label
        SimilarityThreshold1        matlab.ui.control.NumericEditField
        ImageNameLabel              matlab.ui.control.Label
        Filter1SimilarityLabel      matlab.ui.control.Label
        UIAxes3                     matlab.ui.control.UIAxes
        UIAxes4                     matlab.ui.control.UIAxes
        Filter2SimilarityLabel      matlab.ui.control.Label
    end

    
    % global variables
    properties (Access = public)
        % gabor filter arguments
        gamma = 1;
        psi = 0.1;
        theta = 90;
        bw = 2.8;
        lambda = 3.5; 
        pi = 180;
        
        % images are resized before any form of processing
        % desired resolution of the resized image
        resolution = [540; 540];
        
        % Filtering Selections from user
        % 1 = color histogram, 2 = mean gabor, 3 = std gabor
        firstPassFilter = 1;
        secondPassFilter = 2;
        
        % Similarity threshold input from user
        firstPassSimilarityThreshold = 0.3;
        secondPassSimilarityThreshold = 0.4;
        
        % query input image from user 
        queryimg;
        
        similarityValues;
        euclideanDistances;
        fileNames;
    end


    methods (Access = private)

        % Value changed function: uploadButton
        function uploadButtonValueChanged(app, event)
            value = app.uploadButton.Value;
            [filename, pathname] = uigetfile({'*.jpg;*.png;*.gif;*.bmp', 'All Image Files (*.jpg, *.png, *.gif, *.bmp)'; ...
                '*.*',                   'All Files (*.*)'}, ...
                'Pick an image file', 'MultiSelect', 'on');
            app.queryimg = imread([pathname,filename]);
            app.queryimg = imresize(app.queryimg,[app.resolution(1,:) app.resolution(2,:)]); % resize query image
            imshow([pathname,filename],'Parent',app.UIAxes3);
        end

        % Value changed function: FirstFilterDropDown
        function FirstFilterDropDownValueChanged(app, event)
            value = app.FirstFilterDropDown.Value;
            if strcmpi(value, 'HSV Color Histogram')
                app.firstPassFilter = 1;
            end
            if strcmpi(value, 'Gabor Wavelets (mean)')
                app.firstPassFilter = 2;
            end
            if strcmpi(value, 'Gabor Wavelets (std dev)')
                app.firstPassFilter = 3;
            end
            disp(app.firstPassFilter);
        end

        % Value changed function: SecondFilterDropDown
        function SecondFilterDropDownValueChanged(app, event)
            value = app.SecondFilterDropDown.Value;
            if strcmpi(value, 'HSV Color Histogram')
                app.secondPassFilter = 1;
            end
            if strcmpi(value, 'Gabor Wavelets (mean)')
                app.secondPassFilter = 2;
            end
            if strcmpi(value, 'Gabor Wavelets (std dev)')
                app.secondPassFilter = 3;
            end
            disp(app.secondPassFilter);
        end

        % Value changed function: SimilarityThreshold1
        function SimilarityThreshold1ValueChanged(app, event)
            value = app.SimilarityThreshold1.Value;
            app.firstPassSimilarityThreshold = value;
            disp(app.firstPassSimilarityThreshold);
        end

        % Value changed function: SimilarityThreshold2
        function SimilarityThreshold2ValueChanged(app, event)
            value = app.SimilarityThreshold2.Value;
            app.secondPassSimilarityThreshold = value;
            disp(app.secondPassSimilarityThreshold);
        end

        % Button pushed function: goButton
        function goButtonPushed(app, event)
            [simVals, eucliDists, fNames] = goButton(app);
            
            app.similarityValues = simVals;
            app.euclideanDistances = eucliDists;
            app.fileNames = fNames;
            
            disp(fNames);
            app.FilteredimagesListBox.Items = fNames;
            
        end

        % Value changed function: FilteredimagesListBox
        function FilteredimagesListBoxValueChanged(app, event)
            value = app.FilteredimagesListBox.Value;
            disp(value);
            value = num2str(value);
            
            index = 0;
            for ii=1:length(app.fileNames)
                thisCell = app.fileNames(ii,1);
                thisCell = thisCell{1};
                if strcmpi(value,thisCell)
                    index = ii;
                    
                    similarity1 = num2str(app.similarityValues(index, app.firstPassFilter));
                    similarity2 = num2str(app.similarityValues(index, app.secondPassFilter));

                    imshow(value,'Parent',app.UIAxes4);
                    app.ImageNameLabel.Text = ['Image Name: ', value];
                    app.Filter1SimilarityLabel.Text = ['Filter 1 Similarity: ', similarity1];
                    app.Filter2SimilarityLabel.Text = ['Filter 2 Similarity: ', similarity2];
                end
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.FirstFilterDropDownLabel = uilabel(app.UIFigure);
            app.FirstFilterDropDownLabel.Text = 'First Filter';
            app.FirstFilterDropDown = uidropdown(app.UIFigure);
            app.FirstFilterDropDown.Items = {'HSV Color Histogram', 'Gabor Wavelets (mean)', 'Gabor Wavelets (std dev)'};
            app.FirstFilterDropDown.ValueChangedFcn = createCallbackFcn(app, @FirstFilterDropDownValueChanged, true);
            app.FirstFilterDropDown.Value = 'HSV Color Histogram';
            app.SecondFilterDropDownLabel = uilabel(app.UIFigure);
            app.SecondFilterDropDownLabel.Text = 'Second Filter';
            app.SecondFilterDropDown = uidropdown(app.UIFigure);
            app.SecondFilterDropDown.Items = {'HSV Color Histogram', 'Gabor Wavelets (mean)', 'Gabor Wavelets (std dev)'};
            app.SecondFilterDropDown.ValueChangedFcn = createCallbackFcn(app, @SecondFilterDropDownValueChanged, true);
            app.SecondFilterDropDown.Value = 'Gabor Wavelets (mean)';
            app.uploadButton = uibutton(app.UIFigure, 'state');
            app.uploadButton.ValueChangedFcn = createCallbackFcn(app, @uploadButtonValueChanged, true);
            app.uploadButton.Text = 'Upload Image';
            app.FilteredimagesListBoxLabel = uilabel(app.UIFigure);
            app.FilteredimagesListBoxLabel.Text = 'Filtered images';
            app.FilteredimagesListBox = uilistbox(app.UIFigure);
            app.FilteredimagesListBox.Items = {};
            app.FilteredimagesListBox.ValueChangedFcn = createCallbackFcn(app, @FilteredimagesListBoxValueChanged, true);
            app.FilteredimagesListBox.Value = {};
            app.SimilarityThresholdEditField_3Label = uilabel(app.UIFigure);
            app.SimilarityThresholdEditField_3Label.Text = 'Similarity Threshold';
            app.SimilarityThreshold2 = uieditfield(app.UIFigure, 'numeric');
            app.SimilarityThreshold2.Limits = [0 1];
            app.SimilarityThreshold2.ValueChangedFcn = createCallbackFcn(app, @SimilarityThreshold2ValueChanged, true);
            app.goButton = uibutton(app.UIFigure, 'push');
            app.goButton.ButtonPushedFcn = createCallbackFcn(app, @goButtonPushed, true);
            app.goButton.Text = 'Retrieve Similar Images';
            app.SimilarityThresholdEditField_4Label = uilabel(app.UIFigure);
            app.SimilarityThresholdEditField_4Label.Text = 'Similarity Threshold';
            app.SimilarityThreshold1 = uieditfield(app.UIFigure, 'numeric');
            app.SimilarityThreshold1.Limits = [0 1];
            app.SimilarityThreshold1.ValueChangedFcn = createCallbackFcn(app, @SimilarityThreshold1ValueChanged, true);
            app.ImageNameLabel = uilabel(app.UIFigure);
            app.ImageNameLabel.Text = 'Image Name:';
            app.Filter1SimilarityLabel = uilabel(app.UIFigure);
            app.Filter1SimilarityLabel.Text = 'Filter 1 Similarity:';
            app.UIAxes3 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.UIAxes4 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            app.Filter2SimilarityLabel = uilabel(app.UIFigure);
            app.Filter2SimilarityLabel.Text = 'Filter 2 Similarity:';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end